classdef plotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        HelpPushTool                   matlab.ui.container.toolbar.PushTool
        RightPanel                     matlab.ui.container.Panel
        Subpanel                       matlab.ui.container.Panel
        TraceanalysisDropDown          matlab.ui.control.DropDown
        TraceanalysisLabel             matlab.ui.control.Label
        CompareStages                  matlab.ui.control.Button
        CompareTraces                  matlab.ui.control.Button
        SummarizeButton                matlab.ui.control.Button
        TraceSpinner                   matlab.ui.control.Spinner
        TraceSpinnerLabel              matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        LeftUpperPanel                 matlab.ui.container.Panel
        GetDirButton_1                 matlab.ui.control.Button
        FolderEditField                matlab.ui.control.EditField
        YearDropDown                   matlab.ui.control.DropDown
        SiteDropDown                   matlab.ui.control.DropDown
        DatatypeDropDown               matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        FolderEditFieldLabel           matlab.ui.control.Label
        LevelButtonGroup_top           matlab.ui.container.ButtonGroup
        Stage2Button_top               matlab.ui.control.RadioButton
        Stage1Button_top               matlab.ui.control.RadioButton
        RawButton_top                  matlab.ui.control.RadioButton
        TracestoppanelListBox          matlab.ui.control.ListBox
        TracestoppanelListBoxLabel     matlab.ui.control.Label
        YearDropDownLabel              matlab.ui.control.Label
        SiteDropDownLabel              matlab.ui.control.Label
        LeftLowerPanel                 matlab.ui.container.Panel
        MatchSwitch                    matlab.ui.control.Switch
        MatchSwitchLabel               matlab.ui.control.Label
        GetDirButton_2                 matlab.ui.control.Button
        FolderEditField_2              matlab.ui.control.EditField
        YearDropDown_2                 matlab.ui.control.DropDown
        SiteDropDown_2                 matlab.ui.control.DropDown
        DatatypeDropDown_2             matlab.ui.control.DropDown
        LevelButtonGroup_btm           matlab.ui.container.ButtonGroup
        Stage2Button_btm               matlab.ui.control.RadioButton
        Stage1Button_btm               matlab.ui.control.RadioButton
        RawButton_btm                  matlab.ui.control.RadioButton
        DatatypeDropDown_2Label        matlab.ui.control.Label
        SiteDropDown_2Label            matlab.ui.control.Label
        YearDropDown_2Label            matlab.ui.control.Label
        FolderEditField_2Label         matlab.ui.control.Label
        TracesbottompanelListBox       matlab.ui.control.ListBox
        TracesbottompanelListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        uiFont = uistyle("FontColor",'#808080') % Description
        dBase % Used to streamline use of ta_plot_parents.m
    end
    
    methods (Access = private)
        
        % Get rid of plot data if it exists
        function kill_trace(app,panel)
            if panel==1
                ph = app.UIAxes.Children;
                ah = app.UIAxes;
            elseif panel==2
                ph = app.UIAxes2.Children;
                ah = app.UIAxes2;
            end

            if ~isempty(ph)
                ydata = zeros(size(ph.YData));
                ph.YData = ydata;
                ylabel(ah,'')
            end
        end
        
        function [tv, input1, input2] = getTrace_inputs(app)
            input1 = [];
            input2 = [];
            tv = [];
            selectedButton_top = app.LevelButtonGroup_top.SelectedObject;
            selectedButton_btm = app.LevelButtonGroup_btm.SelectedObject;

            % Folder for input #1
            if strcmp(selectedButton_top.Text,'Raw')
                if strcmp(app.SiteDropDown.Value,'ECCC')
                        datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                            app.YearDropDown.Value, app.SiteDropDown.Value,...
                            app.DatatypeDropDown.Value,'30min');
                else
                    datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                end
            elseif strcmp(selectedButton_top.Text,'Stage1')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value,'Clean');
            elseif strcmp(selectedButton_top.Text,'Stage2')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_top.Text,'Stage3')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_top.Text,'Stage3+')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            % Folder for input #2
            if strcmp(selectedButton_btm.Text,'Raw')
                if strcmp(app.SiteDropDown_2.Value,'ECCC')
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'30min');
                else
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                end
            elseif strcmp(selectedButton_btm.Text,'Stage1')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value,'Clean');
            elseif strcmp(selectedButton_btm.Text,'Stage2')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3+')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            if isfolder(datalevelFolder_1)
                year1 = str2double(app.YearDropDown.Value);
                varname_1 = app.TracestoppanelListBox.Value;
                tv = read_bor(fullfile(datalevelFolder_1,'clean_tv'),8,[],year1);

                if isfile(fullfile(datalevelFolder_1,varname_1)) && ~isnan(year1)
                    input1 = read_bor(fullfile(datalevelFolder_1,varname_1),[],[],year1);
                    input1(input1==-9999) = NaN;
                end
            end

            if isfolder(datalevelFolder_2)
                year2 = str2double(app.YearDropDown_2.Value);
                varname_2 = app.TracesbottompanelListBox.Value;

                if isfile(fullfile(datalevelFolder_2,varname_2)) && ~isnan(year2)
                    input2 = read_bor(fullfile(datalevelFolder_2,varname_2),[],[],year2);
                    input2(input2==-9999) = NaN;
                end
            end
        end
        

        % Structure allows use of common code for top & bottom panel items
        function topBtm = get_topBtm_struct(app,value)
            
            if value==1 % Change to top panel item
                topBtm.value = 1;
                topBtm.FolderEditField = 'FolderEditField';
                topBtm.YearDropDown = 'YearDropDown';
                topBtm.SiteDropDown = 'SiteDropDown';
                topBtm.DatatypeDropDown = 'DatatypeDropDown';
                topBtm.LevelButtonGroup = 'LevelButtonGroup_top';
                topBtm.RawButton = 'RawButton_top';
                topBtm.Stage1Button = 'Stage1Button_top';
                topBtm.Stage2Button = 'Stage2Button_top';
                topBtm.TracesListBox = 'TracestoppanelListBox';
                topBtm.UIAxes_primary = 'UIAxes';
                topBtm.UIAxes_secondary = 'UIAxes2';

            elseif value==2 % Change top bottom panel item
                topBtm.value = 2;
                topBtm.FolderEditField = 'FolderEditField_2';
                topBtm.YearDropDown = 'YearDropDown_2';
                topBtm.SiteDropDown = 'SiteDropDown_2';
                topBtm.DatatypeDropDown = 'DatatypeDropDown_2';
                topBtm.LevelButtonGroup = 'LevelButtonGroup_btm';
                topBtm.RawButton = 'RawButton_btm';
                topBtm.Stage1Button = 'Stage1Button_btm';
                topBtm.Stage2Button = 'Stage2Button_btm';
                topBtm.TracesListBox = 'TracesbottompanelListBox';
                topBtm.UIAxes_primary = 'UIAxes2';
                topBtm.UIAxes_secondary = 'UIAxes';
            end
        end
        

        %--- Retrieve list of traces from folder for 'ListBox' ---%
        function Items = update_TracesItems(app,datalevelFolder)
            
            traceNames = dir(datalevelFolder);
            traceNames = traceNames(3:end);
            traceNames = struct2table(traceNames);
            idx1 = ~traceNames.isdir==1;
            idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector') & ~strcmp(traceNames.name,'.DS_Store');
            traceNames = traceNames.name(idx1 & idx2);
            n = length(traceNames);
            Items = cell(1,n+1);
            Items(1) = {'None'};
            if n==1
                Items(2) = {traceNames{1}};
            else
                Items(2:end) = traceNames';
            end
        end
        
        
        %--- Triggered when folder field is changed ---%
        function update_YearDropDown(app,topBtm)
            app.(topBtm.SiteDropDown).Items = {'None'};
            app.(topBtm.SiteDropDown).Value = app.(topBtm.SiteDropDown).Items(1);
            app.(topBtm.DatatypeDropDown).Items = {'None'};
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            folderEditField_value = app.(topBtm.FolderEditField).Value;

            if isfolder(folderEditField_value)
                yearFolders = dir(folderEditField_value);
                yearFolders = yearFolders(3:end);
                yearFolders = struct2table(yearFolders);
                yearFolders = yearFolders(yearFolders.isdir==1,:);
                yearFolders = yearFolders.name;

                % Only retain folder names that could be years
                idx_valid_years = false(length(yearFolders),1);
                for i=1:length(yearFolders)
                    tmp = str2double(yearFolders{i});
                    if ~isempty(tmp)
                        if tmp>1900 && tmp<2199
                            idx_valid_years(i,1) = true;
                        end
                    end
                end
                yearFolders = yearFolders(idx_valid_years);
                % idx = ~strcmp(yearFolders,'Calculation_Procedures');
                % yearFolders = yearFolders(idx);

                if ~isempty(yearFolders)
                    Items = cell(1,length(yearFolders)+1);
                    Items(1) = {'Choose'};
                    Items(2:end) = yearFolders';
                    app.(topBtm.YearDropDown).Items = Items;
                    fid = fopen('lastFolderUsed.ini','w');
                    fprintf(fid,'%s',folderEditField_value);
                    fclose(fid);
                else
                    Items = {'None'};
                    app.(topBtm.YearDropDown).Items = Items;
                end
            else
                Items = {'None'};
                app.(topBtm.YearDropDown).Items = Items;
            end
            app.(topBtm.YearDropDown).Value = app.(topBtm.YearDropDown).Items(1);
        end
        
        
        function update_SiteDropDown(app,topBtm)
            
            app.(topBtm.DatatypeDropDown).Items = {'None'};
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            YearDropDown_value = app.(topBtm.YearDropDown).Value;

            if strcmp(YearDropDown_value,'Choose') || strcmp(YearDropDown_value,'None')
                app.(topBtm.SiteDropDown).Items = {'None'};
            else
                yearFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value);
                if isfolder(yearFolder)
                    siteFolders = dir(yearFolder);
                    siteFolders = siteFolders(3:end);
                    siteFolders = struct2table(siteFolders);
                    n = height(siteFolders);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {siteFolders.name};
                    else
                        Items(2:end) = siteFolders.name';
                    end
                    app.(topBtm.SiteDropDown).Items = Items;
                else
                    app.(topBtm.SiteDropDown).Items = {'None'};
                end
            end
            app.(topBtm.SiteDropDown).Value = app.(topBtm.SiteDropDown).Items(1);
        end
        
        function update_DatatypeDropDown(app,topBtm)
            app.(topBtm.DatatypeDropDown).Enable = 'off';
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.TracesListBox).Enable = 'off';
            app.(topBtm.TracesListBox).Items = {'None'};
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);
            
            % Get rid of plot data if it exists
            kill_trace(app,topBtm.value)

            SiteDropDown_value = app.(topBtm.SiteDropDown).Value;

            if strcmp(SiteDropDown_value,'Choose') || strcmp(SiteDropDown_value,'None')
                app.(topBtm.DatatypeDropDown).Items = {'None'};
            else
                siteFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value,SiteDropDown_value);
                if isfolder(siteFolder)
                    datatypeFolders = dir(siteFolder);
                    datatypeFolders = datatypeFolders(3:end);
                    datatypeFolders = struct2table(datatypeFolders);
                    idx = datatypeFolders.isdir==1;
                    datatypeFolders = datatypeFolders.name(idx,:);
                    n = size(datatypeFolders,1);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {datatypeFolders};
                    else
                        Items(2:end) = datatypeFolders';
                    end
                    app.(topBtm.DatatypeDropDown).Items = Items;
                else
                    app.(topBtm.DatatypeDropDown).Items = {'None'};
                end
            end
            app.(topBtm.DatatypeDropDown).Value = app.(topBtm.DatatypeDropDown).Items(1);
            app.(topBtm.DatatypeDropDown).Enable = 'on';
        end

        %--- Called when Year/Site/Data drop downs are changed ---%
        function update_LevelBox(app,topBtm)
            app.(topBtm.RawButton).Enable = "off";
            app.(topBtm.Stage1Button).Enable = "off";
            app.(topBtm.Stage2Button).Enable = "off";
            app.(topBtm.LevelButtonGroup).SelectedObject = app.(topBtm.RawButton);

            datatype_DropDown_value = app.(topBtm.DatatypeDropDown).Value;

            % Get rid of plot data if it exists
            kill_trace(app,1)

             if strcmp(datatype_DropDown_value,'Choose') || strcmp(datatype_DropDown_value,'None')
                app.(topBtm.TracesListBox).Items = {'None'};
                app.(topBtm.TracesListBox).Enable = 'off';
             else
                 datatypeFolder = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value, datatype_DropDown_value);

                 % Change radio button text dependent on data type selected
                 if strcmp(datatype_DropDown_value,'Clean')
                     app.(topBtm.RawButton).Text = 'Stage2';
                     app.(topBtm.Stage1Button).Text = 'Stage3';
                     app.Stage2Button_top.Text = 'Stage3+';
                 else
                     app.(topBtm.RawButton).Text = 'Raw';
                     app.(topBtm.Stage1Button).Text = 'Stage1';
                     app.(topBtm.Stage2Button).Text = '';
                 end

                 if isfolder(datatypeFolder)
                    % Check folders before turning on other radio buttons
                    if strcmp(datatype_DropDown_value,'Clean')
                        % Check for second stage folder
                        if isfolder(fullfile(datatypeFolder,'SecondStage'))
                            app.(topBtm.RawButton).Enable ="on";
                            datalevelFolder = fullfile(datatypeFolder,'SecondStage');
                        end
                        
                        % Check for third stage folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage'))
                            app.(topBtm.Stage1Button).Enable ="on";
                        end

                        % Check for third stage default Ustar folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage_Default_Ustar'))
                            app.(topBtm.Stage2Button).Enable ="on";
                        end
                    else
                        datalevelFolder = datatypeFolder;

                        % Turn on 'Raw' button by default
                        app.(topBtm.RawButton).Enable = "on";

                        % Check for Stage1 folder before activating button
                        if isfolder(fullfile(datatypeFolder,'Clean'))
                            app.(topBtm.Stage1Button).Enable ="on";
                        end
                    end

                    Items = update_TracesItems(app,datalevelFolder);

                    app.(topBtm.TracesListBox).Items = Items;
                    app.(topBtm.TracesListBox).Enable = 'on';
                 else
                    app.(topBtm.TracesListBox).Items = {'None'};
                    app.(topBtm.TracesListBox).Enable = 'off';
                 end
             end
             
             app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);
        end
        
        
        function update_TracesListBox(app,topBtm)
            selectedButton = app.(topBtm.LevelButtonGroup).SelectedObject;
            
            commonRoot = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value);
            if strcmp(selectedButton.Text,'Raw')
                datalevelFolder = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                datalevelFolder = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                datalevelFolder = fullfile(commonRoot,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            Items =  update_TracesItems(app,datalevelFolder);
            
            app.(topBtm.TracesListBox).Items = Items;
            app.(topBtm.TracesListBox).Value = app.(topBtm.TracesListBox).Items(1);

            if isfolder(datalevelFolder)
                year = str2double(app.(topBtm.YearDropDown).Value);
                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
            
                % convert time vector to Matlab's datetime
                tv_dt = datetime(tv,'ConvertFrom','datenum');

                ydata = zeros(size(tv));

                plot(app.(topBtm.UIAxes_primary), tv_dt, ydata)
                zoom(app.(topBtm.UIAxes_primary),'on')
                try
                    zoom(app.(topBtm.UIAxes_secondary),'out')
                    % linkaxes([app.UIAxes app.UIAxes2],'x')
                end
             end
        end
        
        function update_plotWindow(app,topBtm)
            value = app.(topBtm.TracesListBox).Value;
            selectedButton = app.(topBtm.LevelButtonGroup).SelectedObject;
            
            commonRoot = fullfile(app.(topBtm.FolderEditField).Value,...
                    app.(topBtm.YearDropDown).Value, app.(topBtm.SiteDropDown).Value);

            if strcmp(selectedButton.Text,'Raw')
                databasePth = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                databasePth = fullfile(commonRoot,...
                    app.(topBtm.DatatypeDropDown).Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                databasePth = fullfile(commonRoot,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                databasePth = fullfile(commonRoot,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                databasePth = fullfile(commonRoot,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            year = str2double(app.(topBtm.YearDropDown).Value);
            tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
            
            % convert time vector to Matlab's datetime
            tv_dt = datetime(tv,'ConvertFrom','datenum');

            if strcmp(value,'None')
                ydata = zeros(size(tv));
            else
                ydata = read_bor(fullfile(databasePth,value),[],[],year);
                ydata(ydata==-9999) = NaN;
            end
            
            plot(app.(topBtm.UIAxes_primary), tv_dt, ydata)
            ylabel(app.(topBtm.UIAxes_primary), regexprep(value,'_',' '))
            
            n_missing = sum(isnan(ydata));
            title(app.(topBtm.UIAxes_primary), char(['# samples missing = ' num2str(uint16(n_missing))]))
        end
        
        function trace_str = loadDbase(app,folder_base,site_str,year_str,stage_button)
            nRows = length(app.dBase);
            flag_database_loaded = 0;
            
            for i=1:nRows
                check.folder = strcmp(app.dBase(i).Folder,folder_base);
                check.site = strcmp(app.dBase(i).Site,site_str);
                check.year = strcmp(app.dBase(i).Year,year_str);
                check.stage = app.dBase(i).Stage == stage_button;

                check.sum = check.folder + check.site + check.year + check.stage;
                if check.sum==4
                    trace_str = app.dBase(i).trace_str;
                    flag_database_loaded = 1;
                end
            end
            
            if flag_database_loaded==0 % Default
                row = nRows + 1;
                app.dBase(row).Folder = folder_base;
                app.dBase(row).Site = site_str;
                app.dBase(row).Year = year_str;
                app.dBase(row).Stage = stage_button;

                try
                    % Load database
                    trace_str = fr_cleaning_siteyear(str2double(year_str),site_str,stage_button);

                    % Reduce size of trace_str in memory
                    trace_str = rmfield(trace_str,'DOY');
                    nRows_trace_str = length(trace_str);
                    for i=1:nRows_trace_str
                        trace_str(i).timeVector = single(trace_str(i).timeVector);
                        trace_str(i).data = single(trace_str(i).data);
                        trace_str(i).data_old = single(trace_str(i).data_old);
                    end
                catch
                    trace_str = struct();
                    errordlg('Failed to load database.')
                end

                app.dBase(row).trace_str = trace_str;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile('lastFolderUsed.ini')
                fid = fopen('lastFolderUsed.ini');
                results = fscanf(fid,'%s');
                fclose(fid);
                
                app.FolderEditField.Value = results;
                FolderEditFieldValueChanged(app)
                
                app.FolderEditField_2.Value = results;
                FolderEditField_2ValueChanged(app)
            end

            % Change font color of drop down list items which aren't active
            addStyle(app.TraceanalysisDropDown,app.uiFont,"item",5)

            % Initial dBase structure for use with ta_plot_parents.m
            app.dBase.Folder = 'C:\test';
            app.dBase.Site = 'XXX';
            app.dBase.Year = '1901';
            app.dBase.Stage = 0;
            app.dBase.trace_str = struct();
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_YearDropDown(app,topBtm)
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_SiteDropDown(app,topBtm)
        end

        % Value changed function: SiteDropDown
        function SiteDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_DatatypeDropDown(app,topBtm)
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_LevelBox(app,topBtm)

             % % load time vector
             % year = str2double(app.YearDropDown.Value);
             % databasePth = fullfile(app.FolderEditField.Value, app.YearDropDown.Value,...
             %     app.SiteDropDown.Value, app.DatatypeDropDown.Value);
             % 
             % if isfolder(databasePth)
             %    tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
             % 
             %    % convert time vector to Matlab's datetime
             %    tv_dt = datetime(tv,'ConvertFrom','datenum');
             % 
             %    ydata = zeros(size(tv));
             % 
             %    plot(app.UIAxes, tv_dt, ydata)
             %    zoom(app.UIAxes,'on')
             %    % plot(app.UIAxes2, tv_dt, ydata)
             %    % zoom(app.UIAxes2,'on')
             %    try
             %        zoom(app.UIAxes2,'reset')
             %        % linkaxes([app.UIAxes app.UIAxes2],'x')
             %    end
             % end
        end

        % Value changed function: TracestoppanelListBox
        function TracestoppanelListBoxValueChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_plotWindow(app, topBtm)
        end

        % Value changed function: TracesbottompanelListBox
        function TracesbottompanelListBoxValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_plotWindow(app, topBtm)
        end

        % Value changed function: FolderEditField_2
        function FolderEditField_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_YearDropDown(app,topBtm)
        end

        % Value changed function: YearDropDown_2
        function YearDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_SiteDropDown(app,topBtm)
        end

        % Value changed function: SiteDropDown_2
        function SiteDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_DatatypeDropDown(app,topBtm)
        end

        % Value changed function: DatatypeDropDown_2
        function DatatypeDropDown_2ValueChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_LevelBox(app,topBtm)
        end

        % Selection changed function: LevelButtonGroup_btm
        function LevelButtonGroup_btmSelectionChanged(app, event)
            topBtm = get_topBtm_struct(app,2);
            update_TracesListBox(app,topBtm)
        end

        % Button pushed function: CompareTraces
        function CompareTracesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);
            
            % Only compare traces from the same year
            input1_year = app.YearDropDown.Value;
            input2_year = app.YearDropDown_2.Value;

            years = (1900:2099)';
            years_str = num2str(years);
            valid_years = cellstr(years_str);

            if sum(strcmp(valid_years,input1_year))==1 &&...
                    sum(strcmp(valid_years,input2_year))==1
                if ~strcmp(input1_year,input2_year)
                    msgStr = 'Years must be the same.';
                    plotFlag = 0;
                end

                if strcmp(app.TracestoppanelListBox.Value,'None') ||...
                        strcmp(app.TracesbottompanelListBox.Value,'None')
                    msgStr = 'Choose valid traces.';
                    plotFlag = 0;
                end
            else
                msgStr = 'Choose a valid year.';
                plotFlag = 0;
            end

            if plotFlag==1
                simple_trace_comparison(tv, input1, input2)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
            end
        end

        % Button pushed function: SummarizeButton
        function SummarizeButtonPushed(app, event)
            plotFlag = 0;
            msgStr = '';
            selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            traceValue = app.TraceSpinner.Value;
            
            if traceValue==1
                msgStr = 'Trace #1: ';
                % Folder for input #1
                % if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                % elseif strcmp(selectedButton.Text,'Stage1')
                %     datalevelFolder_1 = fullfile(app.FolderEditField_2.Value,...
                %         app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                %         app.DatatypeDropDown_2.Value,'Clean');
                % end
            elseif traceValue==2
                msgStr = 'Trace #2: ';

                % Folder for input #2
                if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                elseif strcmp(selectedButton.Text,'Stage1')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'Clean');
                elseif strcmp(selectedButton.Text,'Stage2')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','SecondStage');
                elseif strcmp(selectedButton.Text,'Stage3')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage');
                elseif strcmp(selectedButton.Text,'Stage3+')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage_Default_Ustar');
                end
            end

            if isfolder(datalevelFolder)
                if traceValue==1
                    year = str2double(app.YearDropDown.Value);
                    varname = app.TracestoppanelListBox.Value;
                elseif traceValue==2
                    year = str2double(app.YearDropDown_2.Value);
                    varname = app.TracesbottompanelListBox.Value;
                end

                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
                
                if isfile(fullfile(datalevelFolder,varname)) && ~isnan(year)
                    input1 = read_bor(fullfile(datalevelFolder,varname),[],[],year);
                    input1(input1==-9999) = NaN;
                    plotFlag = 1;
                end
            end

            if plotFlag==1
                simple_trace_summary(tv, input1)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                msgStr = [msgStr 'No trace to summarize.'];
                errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
            end
        end

        % Size changed function: Subpanel
        function SubpanelSizeChanged(app, event)
            position = app.Subpanel.Position;
            
        end

        % Button pushed function: GetDirButton_1
        function GetDirButton_1Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField.Value = fldr;
            FolderEditFieldValueChanged(app)
        end

        % Button pushed function: GetDirButton_2
        function GetDirButton_2Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField_2.Value = fldr;
            FolderEditField_2ValueChanged(app)
        end

        % Selection changed function: LevelButtonGroup_top
        function LevelButtonGroup_topSelectionChanged(app, event)
            topBtm = get_topBtm_struct(app,1);
            update_TracesListBox(app,topBtm)
        end

        % Button pushed function: CompareStages
        function CompareStagesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);

            yearX = datevec(tv(1));
            siteID = app.SiteDropDown.Value;
            disp(siteID)
            if plotFlag==1 & ~isempty(input1) & ~isempty(input2)
                fr_compare_database_traces2(siteID,yearX,[],[],[],...
                    [],[],input1,input2)
            end
        end

        % Callback function: HelpPushTool
        function HelpPushToolClicked(app, event)
            message = {'This app was initiallly designed to quickly view traces in the database and select potential alternate traces to use for gap filling.',...
                '',...
                'Type in a siteID\Database\ folder name or use the browse button to select the directory to search for a trace.',...
                '',...
                'The [Summarize] button generates several summary plots for a selected trace in a new window. The trace that is summrized is based on the value in the [Trace] field (i.e. 1-top; 2-bottom).',...
                '',...
                'The [Compare] button generates several comparison plots for the two selected traces in a new window. It only allows comparisons of traces from the same year (if working properly). Different sites, data types, and levels can be compared.',...
                '',...
                'For both [Compare] and [Summarize], only the upper left panel in the new window will be interactive (i.e. zoom/pan). The data in the other sub-panels will automatically update based on what is visible in the main window.',...
                '',...
                'The shaded out button will allow you to examine changes in a trace across stages using Zoran`s fr_compare_database_traces(). Since trace names might differ between the raw database and subsequent stages, robust error checking is still in progress...',...
                '',...
                '',...
                'In the future, the help button will ideally open documentation on GitHub.',...
                ''};

            msgbox(message,'Overview','modal');
        end

        % Value changed function: MatchSwitch
        function MatchSwitchValueChanged(app, event)
            value = app.MatchSwitch.Value;
            
            if strcmp(value,'On')
                app.FolderEditField.Enable = 'off';
                app.YearDropDown_2.Enable = 'off';
                app.SiteDropDown_2.Enable = 'off';

                app.YearDropDown_2.Value = app.YearDropDown.Value;
                app.SiteDropDown_2.Value = app.SiteDropDown.Value;
                app.FolderEditField_2.Value = app.FolderEditField.Value;
            elseif strcmp(value,'Off')
                app.FolderEditField_2.Enable = 'on';
                app.YearDropDown_2.Enable = 'on';
                app.SiteDropDown_2.Enable = 'on';
            end
        end

        % Value changed function: TraceanalysisDropDown
        function TraceanalysisDropDownValueChanged(app, event)
            value = app.TraceanalysisDropDown.Value;
            app.TraceanalysisDropDown.Enable = 'off';
            msgStruct.Interpreter = 'tex';
            msgStruct.WindowStyle = 'non-modal';

            if strcmpi(value,'Compare stages')
                if 1==2 % Not ready yet
                    [tv, input1, input2] = getTrace_inputs(app);
    
                    yearX = datevec(tv(1));
                    siteID = app.SiteDropDown.Value;
                    disp(siteID)
                    if ~isempty(input1) & ~isempty(input2)
                        fr_compare_database_traces2(siteID,yearX,[],[],[],...
                            [],[],input1,input2)
                    end
                end
            elseif strcmpi(value,'Compare traces')
                msgStr = '';
                [tv, input1, input2] = getTrace_inputs(app);

                % Only compare traces from the same year
                input1_year = app.YearDropDown.Value;
                input2_year = app.YearDropDown_2.Value;
    
                years = (1900:2099)';
                years_str = num2str(years);
                valid_years = cellstr(years_str);
    
                if sum(strcmp(valid_years,input1_year))==1 &&...
                        sum(strcmp(valid_years,input2_year))==1
                    plotFlag = 1;

                    if ~strcmp(input1_year,input2_year)
                        msgStr = [msgStr 'Years must be the same. '];
                        plotFlag = 0;
                    end
    
                    if strcmp(app.TracestoppanelListBox.Value,'None') &&...
                            strcmp(app.TracesbottompanelListBox.Value,'None')
                        msgStr = [msgStr 'No traces selected. '];
                        plotFlag = 0;
                    elseif strcmp(app.TracestoppanelListBox.Value,'None')
                        msgStr = [msgStr 'Trace #1 not selected. '];
                        plotFlag = 0;
                    elseif strcmp(app.TracesbottompanelListBox.Value,'None')
                        msgStr = [msgStr 'Trace #2 not selected. '];
                        plotFlag = 0;
                    end
                else
                    msgStr = [msgStr 'Choose a valid year -- '];
                    if sum(strcmp(valid_years,input1_year))~=1
                        msgStr = [msgStr 'Trace #1 -- '];
                    end
                    
                    if sum(strcmp(valid_years,input2_year))~=1
                        msgStr = [msgStr 'Trace #2 -- '];
                    end
                    plotFlag = 0;
                end
    
                if plotFlag==1
                    simple_trace_comparison(tv, input1, input2)
                else
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end
            elseif strcmp(value,'Single trace')
                % selectedButton = app.LevelButtonGroup_btm.SelectedObject;
                traceValue = app.TraceSpinner.Value;

                if traceValue==1
                    msgStr = 'Trace #1: ';
                    [tv, input, ~] = getTrace_inputs(app);
                elseif traceValue==2
                    msgStr = 'Trace #2: ';
                    [tv, ~, input] = getTrace_inputs(app);
                end
    
                if ~isempty(input)
                    simple_trace_summary(tv, input)
                else
                    msgStr = [msgStr 'No trace to summarize.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end
            elseif strcmpi(value,'Plot parents')
                % Should everything be turned off while database retrieval
                % is occuring?
                msgStr = '';
                
                if app.TraceSpinner.Value==1
                    msgStr = [msgStr 'Trace #1: '];

                    varname = app.TracestoppanelListBox.Value;
                    folder_base = app.FolderEditField.Value;
                    year_str = app.YearDropDown.Value;
                    site_str = app.SiteDropDown.Value;
                    if app.Stage1Button_top.Value==1
                        stage_button=1;
                    elseif app.RawButton_top.Value==1
                        stage_button=0;
                    elseif app.Stage2Button_top==1
                        stage_button=2;
                    end
                elseif app.TraceSpinner.Value==2
                    msgStr = [msgStr 'Trace #2: '];

                    varname = app.TracesbottompanelListBox.Value;
                    folder_base = app.FolderEditField_2.Value;
                    year_str = app.YearDropDown_2.Value;
                    site_str = app.SiteDropDown_2.Value;
                    if app.Stage1Button_btm.Value==1
                        stage_button=1;
                    elseif app.RawButton_btm.Value==1
                        stage_button=0;
                    elseif app.Stage2Button_btm==1
                        stage_button=2;
                    end
                end

                % Need to update to handle when >Stage 2
                if stage_button==1
                    filename = char([site_str '_' year_str...
                        '_FirstStage_stats.mat']);

                    % Load database if first time for folder/site/year/stage
                    trace_str = loadDbase(app,folder_base,site_str,year_str,stage_button);
                elseif stage_button==0
                    % Raw stage doesn't have parents
                    filename = [];

                    msgStr = [msgStr 'Raw traces do not have parents.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                elseif stage_button==2
                    % Not yet implemented
                    filename = [];

                    msgStr = [msgStr 'Only implemented in stage #1 so far.'];
                    errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                end

                % folder_load = fullfile(folder_base,'Calculation_Procedures',...
                %     'TraceAnalysis_ini',site_str,'Derived_Variables');
                % 
                % % Load in data structure
                % if ~isempty(filename)
                %     folder_file = fullfile(folder_load,filename);
                %     if isfile(folder_file)
                %         load(folder_file)
                %     else
                %         msgStr = [msgStr char(['File ' regexprep(filename,'_','\\_') ' not found.'])];
                %         errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                %     end
                % end

                try
                    if ~isfield(trace_str,'data') || ~isfield(trace_str,'data_old') ||...
                            ~isfield(trace_str,'timeVector')
                        msgStr = 'Data structures missing.';
                        errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                    else
                        indVar = 0;
                        for cntTraces = 1:length(trace_str)
                            if strcmp(trace_str(cntTraces).variableName,varname)
                                indVar = cntTraces;
                                break
                            end
                        end

                        if ~isempty(trace_str(indVar).ind_parents)
                            ta_plot_parents([],trace_str,varname);
                        else
                            % Add dialogue box to tell user trace has no parents
                            msgStr = char(['The selected [' varname...
                                '] trace has no parents.']);
                            errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
                        end
                    end
                end
            end

            app.TraceanalysisDropDown.Value = 'Choose';
            app.TraceanalysisDropDown.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.ClickedCallback = createCallbackFcn(app, @HelpPushToolClicked, true);
            app.HelpPushTool.Icon = 'Help_24.png';
            app.LeftLowerPanel = uipanel(app.UIFigure);
            app.TracesbottompanelListBoxLabel = uilabel(app.LeftLowerPanel);
            app.TracesbottompanelListBoxLabel.Enable = 'off';
            app.TracesbottompanelListBoxLabel.Text = 'Traces (bottom  panel)';
            app.TracesbottompanelListBox = uilistbox(app.LeftLowerPanel);
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracesbottompanelListBoxValueChanged, true);
            app.TracesbottompanelListBox.Enable = 'off';
            app.TracesbottompanelListBox.Value = 'None';
            app.FolderEditField_2Label = uilabel(app.LeftLowerPanel);
            app.FolderEditField_2Label.Text = 'Folder';
            app.YearDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.YearDropDown_2Label.Text = 'Year';
            app.SiteDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.SiteDropDown_2Label.Text = 'Site';
            app.DatatypeDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.DatatypeDropDown_2Label.Text = 'Data type';
            app.LevelButtonGroup_btm = uibuttongroup(app.LeftLowerPanel);
            app.LevelButtonGroup_btm.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_btmSelectionChanged, true);
            app.LevelButtonGroup_btm.Title = 'Level';
            app.RawButton_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.RawButton_btm.Enable = 'off';
            app.RawButton_btm.Text = 'Raw';
            app.RawButton_btm.Value = true;
            app.Stage1Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage1Button_btm.Enable = 'off';
            app.Stage1Button_btm.Text = 'Stage1';
            app.Stage2Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage2Button_btm.Enable = 'off';
            app.Stage2Button_btm.Text = 'Stage2';
            app.DatatypeDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.DatatypeDropDown_2.Items = {'None'};
            app.DatatypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDown_2ValueChanged, true);
            app.DatatypeDropDown_2.Value = 'None';
            app.SiteDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.SiteDropDown_2.Items = {'None'};
            app.SiteDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SiteDropDown_2ValueChanged, true);
            app.SiteDropDown_2.Value = 'None';
            app.YearDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.YearDropDown_2.Items = {'2999'};
            app.YearDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YearDropDown_2ValueChanged, true);
            app.YearDropDown_2.Value = '2999';
            app.FolderEditField_2 = uieditfield(app.LeftLowerPanel, 'text');
            app.FolderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FolderEditField_2ValueChanged, true);
            app.FolderEditField_2.Tooltip = {'Type in database folder path.'};
            app.GetDirButton_2 = uibutton(app.LeftLowerPanel, 'push');
            app.GetDirButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_2Pushed, true);
            app.GetDirButton_2.Icon = 'Browse_16.png';
            app.GetDirButton_2.Tooltip = {'Select database folder'};
            app.GetDirButton_2.Text = '';
            app.MatchSwitchLabel = uilabel(app.LeftLowerPanel);
            app.MatchSwitchLabel.Enable = 'off';
            app.MatchSwitchLabel.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top '};
            app.MatchSwitchLabel.Text = 'Match';
            app.MatchSwitch = uiswitch(app.LeftLowerPanel, 'slider');
            app.MatchSwitch.ValueChangedFcn = createCallbackFcn(app, @MatchSwitchValueChanged, true);
            app.MatchSwitch.Enable = 'off';
            app.MatchSwitch.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top panel.'};
            app.LeftUpperPanel = uipanel(app.UIFigure);
            app.SiteDropDownLabel = uilabel(app.LeftUpperPanel);
            app.SiteDropDownLabel.Text = 'Site';
            app.YearDropDownLabel = uilabel(app.LeftUpperPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.TracestoppanelListBoxLabel = uilabel(app.LeftUpperPanel);
            app.TracestoppanelListBoxLabel.Enable = 'off';
            app.TracestoppanelListBoxLabel.Text = 'Traces (top panel)';
            app.TracestoppanelListBox = uilistbox(app.LeftUpperPanel);
            app.TracestoppanelListBox.Items = {'None'};
            app.TracestoppanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracestoppanelListBoxValueChanged, true);
            app.TracestoppanelListBox.Enable = 'off';
            app.TracestoppanelListBox.Value = 'None';
            app.LevelButtonGroup_top = uibuttongroup(app.LeftUpperPanel);
            app.LevelButtonGroup_top.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_topSelectionChanged, true);
            app.LevelButtonGroup_top.Title = 'Level';
            app.RawButton_top = uiradiobutton(app.LevelButtonGroup_top);
            app.RawButton_top.Enable = 'off';
            app.RawButton_top.Text = 'Raw';
            app.RawButton_top.Value = true;
            app.Stage1Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage1Button_top.Enable = 'off';
            app.Stage1Button_top.Text = 'Stage1';
            app.Stage2Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage2Button_top.Enable = 'off';
            app.Stage2Button_top.Text = 'Stage2';
            app.FolderEditFieldLabel = uilabel(app.LeftUpperPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.DatatypeDropDownLabel = uilabel(app.LeftUpperPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftUpperPanel);
            app.DatatypeDropDown.Items = {'None'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'None';
            app.SiteDropDown = uidropdown(app.LeftUpperPanel);
            app.SiteDropDown.Items = {'None'};
            app.SiteDropDown.ValueChangedFcn = createCallbackFcn(app, @SiteDropDownValueChanged, true);
            app.SiteDropDown.Value = 'None';
            app.YearDropDown = uidropdown(app.LeftUpperPanel);
            app.YearDropDown.Items = {'2999'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2999';
            app.FolderEditField = uieditfield(app.LeftUpperPanel, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderEditField.Tooltip = {'Type in database folder path.'};
            app.GetDirButton_1 = uibutton(app.LeftUpperPanel, 'push');
            app.GetDirButton_1.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_1Pushed, true);
            app.GetDirButton_1.Icon = 'Browse_16.png';
            app.GetDirButton_1.Tooltip = {'Select database folder.'};
            app.GetDirButton_1.Text = '';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Subpanel = uipanel(app.RightPanel);
            app.Subpanel.SizeChangedFcn = createCallbackFcn(app, @SubpanelSizeChanged, true);
            app.TraceSpinnerLabel = uilabel(app.Subpanel);
            app.TraceSpinnerLabel.Text = 'Trace';
            app.TraceSpinner = uispinner(app.Subpanel);
            app.TraceSpinner.Limits = [1 2];
            app.TraceSpinner.Tooltip = {'Plot to [Summarize]: 1 - top; 2- bottom'};
            app.TraceSpinner.Value = 1;
            app.SummarizeButton = uibutton(app.Subpanel, 'push');
            app.SummarizeButton.ButtonPushedFcn = createCallbackFcn(app, @SummarizeButtonPushed, true);
            app.SummarizeButton.Visible = 'off';
            app.SummarizeButton.Tooltip = {'Basic summary plots for selected trace.'};
            app.SummarizeButton.Text = 'Summarize';
            app.CompareTraces = uibutton(app.Subpanel, 'push');
            app.CompareTraces.ButtonPushedFcn = createCallbackFcn(app, @CompareTracesPushed, true);
            app.CompareTraces.Visible = 'off';
            app.CompareTraces.Tooltip = {'Basic comparison plots between traces.'};
            app.CompareTraces.Text = 'Compare';
            app.CompareStages = uibutton(app.Subpanel, 'push');
            app.CompareStages.ButtonPushedFcn = createCallbackFcn(app, @CompareStagesPushed, true);
            app.CompareStages.Icon = 'Compare_16.png';
            app.CompareStages.Enable = 'off';
            app.CompareStages.Visible = 'off';
            app.CompareStages.Tooltip = {'Will run fr_compare_database_traces()'};
            app.CompareStages.Text = '';
            app.TraceanalysisLabel = uilabel(app.Subpanel);
            app.TraceanalysisLabel.Text = 'Trace analysis';
            app.TraceanalysisDropDown = uidropdown(app.Subpanel);
            app.TraceanalysisDropDown.Items = {'Choose', 'Single trace', 'Plot parents', 'Compare traces', 'Compare stages'};
            app.TraceanalysisDropDown.ValueChangedFcn = createCallbackFcn(app, @TraceanalysisDropDownValueChanged, true);
            app.TraceanalysisDropDown.Value = 'Choose';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end